classdef adohe_fototoko < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OpenMenu                  matlab.ui.container.Menu
        ToolsMenu                 matlab.ui.container.Menu
        HistogramMenu             matlab.ui.container.Menu
        BrigthnessMenu            matlab.ui.container.Menu
        NegativeMenu              matlab.ui.container.Menu
        HelpMenu                  matlab.ui.container.Menu
        AboutFototokoMenu         matlab.ui.container.Menu
        HelpMenu_2                matlab.ui.container.Menu
        Toolbar                   matlab.ui.container.Toolbar
        PushTool                  matlab.ui.container.toolbar.PushTool
        PushTool2                 matlab.ui.container.toolbar.PushTool
        PushTool3                 matlab.ui.container.toolbar.PushTool
        GridLayout                matlab.ui.container.GridLayout
        TabGroup                  matlab.ui.container.TabGroup
        HistogramTab              matlab.ui.container.Tab
        BrightnessTab             matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        ApplyButton_2             matlab.ui.control.Button
        EnteradanbvaluesarbLabel  matlab.ui.control.Label
        BrightnessB               matlab.ui.control.NumericEditField
        bEditFieldLabel           matlab.ui.control.Label
        ProcessButton             matlab.ui.control.Button
        BrightnessA               matlab.ui.control.NumericEditField
        aEditFieldLabel           matlab.ui.control.Label
        NegativeTab               matlab.ui.container.Tab
        GridLayout3               matlab.ui.container.GridLayout
        ApplyButton               matlab.ui.control.Button
        ProcessButton_2           matlab.ui.control.Button
        OutputImage               matlab.ui.control.Image
        InputImage                matlab.ui.control.Image
    end

    
    properties (Access = private)
        Fullimagename % Full image name
        Inputimagedata % Image data (input)
        Outputimagedata % Image data (output)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenMenu
        function openImage(app, event)
            [file,path,indx] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp', 'Image Files (*.jpg,*.png,*.jpeg,*.bmp)'}, 'Select an Image File');
            if isequal(file,0)
               disp('User selected Cancel')
            else
               app.Fullimagename = fullfile(path, file);
               app.Inputimagedata = imread(app.Fullimagename);
               dm = ndims(app.Inputimagedata);
               if dm == 2
                   tmp = app.Inputimagedata(:,:);
                   app.Inputimagedata(:,:,1) = tmp;
                   app.Inputimagedata(:,:,2) = tmp;
                   app.Inputimagedata(:,:,3) = tmp;
               end
               app.Outputimagedata = app.Inputimagedata(:,:,:);

               app.InputImage.ImageSource = app.Inputimagedata;
               app.OutputImage.ImageSource = app.Outputimagedata;
            end
        end

        % Button pushed function: ProcessButton
        function processBrightness(app, event)
            sz = size(app.Inputimagedata);
            for i = 1:sz(1)
                for j = 1:sz(2)
                    for k = 1:sz(3)
                        s = app.BrightnessA.Value * app.Inputimagedata(i,j,k) + app.BrightnessB.Value;
                        if s > 255
                            s = 255;
                        elseif s < 0
                            s = 0;
                        end
                        app.Outputimagedata(i,j,k) = s;
                    end
                end
            end
            
            app.OutputImage.ImageSource = app.Outputimagedata;
        end

        % Button pushed function: ProcessButton_2
        function processNegative(app, event)
            app.Outputimagedata = 255 - app.Inputimagedata;
            app.OutputImage.ImageSource = app.Outputimagedata;
        end

        % Button pushed function: ApplyButton, ApplyButton_2
        function apply(app, event)
            app.Inputimagedata = app.Outputimagedata;
            app.InputImage.ImageSource = app.Inputimagedata;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @openImage, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open...';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.HistogramMenu = uimenu(app.ToolsMenu);
            app.HistogramMenu.Text = 'Histogram';
            app.BrigthnessMenu = uimenu(app.ToolsMenu);
            app.BrigthnessMenu.Text = 'Brigthness';
            app.NegativeMenu = uimenu(app.ToolsMenu);
            app.NegativeMenu.Text = 'Negative';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutFototokoMenu = uimenu(app.HelpMenu);
            app.AboutFototokoMenu.Text = 'About Fototoko..';
            app.HelpMenu_2 = uimenu(app.HelpMenu);
            app.HelpMenu_2.Text = 'Help...';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'icon_chart.svg');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'icon_sun.svg');
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Icon = fullfile(pathToMLAPP, 'icon_minus.svg');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.InputImage = uiimage(app.GridLayout);
            app.OutputImage = uiimage(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.BrightnessTab = uitab(app.TabGroup);
            app.BrightnessTab.Title = 'Brightness';
            app.GridLayout2 = uigridlayout(app.BrightnessTab);
            app.aEditFieldLabel = uilabel(app.GridLayout2);
            app.aEditFieldLabel.Text = 'a';
            app.BrightnessA = uieditfield(app.GridLayout2, 'numeric');
            app.BrightnessA.Value = 1;
            app.ProcessButton = uibutton(app.GridLayout2, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @processBrightness, true);
            app.ProcessButton.Text = 'Process';
            app.bEditFieldLabel = uilabel(app.GridLayout2);
            app.bEditFieldLabel.Text = 'b';
            app.BrightnessB = uieditfield(app.GridLayout2, 'numeric');
            app.EnteradanbvaluesarbLabel = uilabel(app.GridLayout2);
            app.EnteradanbvaluesarbLabel.Text = 'Enter a dan b value (s = ar + b)';
            app.ApplyButton_2 = uibutton(app.GridLayout2, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplyButton_2.Text = 'Apply';
            app.NegativeTab = uitab(app.TabGroup);
            app.NegativeTab.Title = 'Negative';
            app.GridLayout3 = uigridlayout(app.NegativeTab);
            app.ProcessButton_2 = uibutton(app.GridLayout3, 'push');
            app.ProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @processNegative, true);
            app.ProcessButton_2.Text = 'Process';
            app.ApplyButton = uibutton(app.GridLayout3, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adohe_fototoko

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end